classdef AnalystBeta < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        File                     matlab.ui.container.Menu
        Print                    matlab.ui.container.Menu
        Export                   matlab.ui.container.Menu
        Exit                     matlab.ui.container.Menu
        Channel                  matlab.ui.container.Menu
        Add                      matlab.ui.container.Menu
        Remove                   matlab.ui.container.Menu
        ClearStackMenu           matlab.ui.container.Menu
        Analys                   matlab.ui.container.Menu
        SignalSettingPanel       matlab.ui.container.Panel
        secLabel                 matlab.ui.control.Label
        TimeEditField            matlab.ui.control.NumericEditField
        TimeEditFieldLabel       matlab.ui.control.Label
        ComfirmButton            matlab.ui.control.Button
        CancelButton             matlab.ui.control.Button
        WindowDropDown           matlab.ui.control.DropDown
        WindowDropDownLabel      matlab.ui.control.Label
        HzLabel                  matlab.ui.control.Label
        FrequencyEditField       matlab.ui.control.EditField
        FrequencyEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        SignalStack              cell
        signal            (1, :) double = []
        nInterval         (1, 1) double = 0
        samplingTime      (1, :) double = []
        samplingFrequency (1, 1) double = 8
    end
    
    methods (Access = public)
        function app = AddInterval(app, sampling_time, frequency_list, winfunc)
            arguments
                app
                sampling_time  = 1
                frequency_list = 1
                winfunc        = 'rectwin'
            end
            app.nInterval = app.nInterval + 1;

            si = SignalInterval(frequency_list, 8, sampling_time, winfunc);
            app.SignalStack(app.nInterval) = {si};

            app.samplingTime = cat(2, app.samplingTime, si.samplingTime);
            sampling_Frequency = lcm(app.samplingFrequency, si.samplingFrequency);
            app.signal = [];
            for i = 1:app.nInterval
                si = app.SignalStack{i};
                si.Sampling(sampling_Frequency/si.samplingFrequency);
                app.signal = cat(2, app.signal, si.signal);
            end
            app.samplingFrequency = sampling_Frequency;
        end

        function app = RemoveInterval(app)
            if app.nInterval > 1
                app.signal(end-length(app.SignalStack{app.nInterval}.signal)+1:end) = [];
                delete(app.SignalStack{app.nInterval});
                app.samplingTime(app.nInterval) = [];
                app.nInterval = app.nInterval - 1;
            end
        end

        function app = PlotSpectrum(app)
            if app.nInterval == 0
                return
            end
            time = linspace(0, sum(app.samplingTime), app.samplingFrequency*sum(app.samplingTime));
            
            ax = subplot(3, app.nInterval, 1:app.nInterval,'Parent', app.UIFigure);
            plot(ax, time, app.signal, 'k' ,'LineWidth', .8)
            grid(ax, 'on');
            ax.FontSize      = 10;
            ax.XLim          = [0, sum(app.samplingTime)];
            ax.YLim          = [-1.2, 1.2];
            ax.XLabel.String = 't/s';
            ax.YLabel.String = 'Amplitude/A';
            ax.Title.String  = 'Time domain signal';

            for i = 1:app.nInterval
                si = app.SignalStack{i};
           
                ax1 = subplot(3, app.nInterval, app.nInterval+i,'Parent', app.UIFigure);
                semilogx(ax1, si.frequency, si.amplitude, 'k' , ...
                    'LineWidth', .8)
                hold(ax1, 'on');
                grid(ax1, 'on');
                semilogx(ax1, si.dominantFrequency, si.dominantAmplitude, 'ro', ...
                    'MarkerSize', 5)
                ax1.FontSize = 10;
                ax1.XLabel.String = 'Frequency/Hz';
                if (i == 1)
                    ax1.YLabel.String = 'Amplitude/A';
                end
                ax1.Title.String = ['Signal ', num2str(i)];
                hold(ax1, 'off');

                ax2 = subplot(3, app.nInterval, app.nInterval*2+1:app.nInterval*3,'Parent', app.UIFigure);
                colorchar = ['r', 'g', 'b', 'c', 'm', 'y', 'k'];
                semilogx(ax2, si.frequency, si.amplitude, colorchar(i), ...
                    'LineWidth', .8, ...
                    'DisplayName', ['Signal ', num2str(i)])

                hold(ax2, 'on');
                grid(ax2, 'on');
                semilogx(ax2, si.dominantFrequency, si.dominantAmplitude, 'o', ...
                    'MarkerSize', 5, ...
                    'MarkerEdgeColor', 'k', ...
                    'HandleVisibility', 'off')
                ax2.FontSize = 10;
                ax2.XLabel.String = 'Frequency/Hz';
                ax2.YLabel.String = 'Amplitude/A';
                ax2.Title.String = 'Specturm';
            end

            legend(ax2, 'Location', 'northwest');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: not associated with a component
        function OpenMenuSelected(app, event)
            filterSpec = {'*.txt'};
            [fileName, folderPath] = uigetfile(filterSpec, 'Select the File');
            fullFilePath = fullfile(folderPath, fileName);
            try

            end
        end

        % Callback function: not associated with a component
        function SaveSelected(app, event)
            filterSpec = {'*.txt'};
            [fileName, folderPath] = uiputfile(filterSpec, 'Save File', 'untitled');
            fullPath = fullfile(folderPath, fileName);
            try

            end
        end

        % Menu selected function: Add
        function AddMenuSelected(app, event)
            app.SignalSettingPanel.Visible = "on";
            % app.AddInterval();
        end

        % Menu selected function: Print
        function PrintMenuSelected(app, event)
            all_axes = findobj(app.UIFigure, 'Type', 'axes');
            f = figure('Visible', 'off','Position',[100,55,1400,700],'Color','w');
            copyobj(all_axes, f);
            printdlg(f);
            close(f);
        end

        % Menu selected function: Export
        function ExportMenuSelected(app, event)
            filterSpec = {'*.png'};
            [fileName, folderPath] = uiputfile(filterSpec, 'Export the Picture', 'untitled');
            fullPath = fullfile(folderPath, fileName);
            try
                all_axes = findobj(app.UIFigure, 'Type', 'axes');
                f = figure('Visible', 'off','Position',[100,55,1400,700],'Color','w');
                copyobj(all_axes, f);
                exportapp(f, fullPath);
                close(f);
            catch ME
                uialert(app.UIFigure, "Export failed：" + ME.message, "Error");
            end
        end

        % Menu selected function: Exit
        function ExitMenuSelected(app, event)
            choice = uiconfirm(app.UIFigure, ...
                'Are you sure you want to exit the program?', ... 
                'Confirm to exit', ...          
                'Options', {'Exit', 'Cancel'}, ...
                'DefaultOption', 'Cancel');
            if strcmp(choice, 'Exit')
                delete(app.UIFigure);
            end
        end

        % Menu selected function: Remove
        function RemoveMenuSelected(app, event)
            app.RemoveInterval();
        end

        % Menu selected function: Analys
        function AnalysMenuSelected(app, event)
            axList = findobj(app.UIFigure, 'Type', 'axes');
            delete(axList);
            app.PlotSpectrum();
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.SignalSettingPanel.Visible = 'off';
        end

        % Button pushed function: ComfirmButton
        function ComfirmButtonPushed(app, event)
            frequency_list = str2num(app.FrequencyEditField.Value);
            window_method = app.WindowDropDown.Value;
            app.AddInterval(app.TimeEditField.Value, frequency_list, window_method);
            app.SignalSettingPanel.Visible = 'off';
        end

        % Callback function: not associated with a component
        function ClearMenuSelected(app, event)
            app.SignalStack       = cell(1,5);
            app.signal            = [];
            app.nInterval         = 0;
            app.samplingTime      = [];
            app.samplingFrequency = 8;
        end

        % Menu selected function: ClearStackMenu
        function ClearStackMenuSelected(app, event)
            app.SignalStack       = cell(1,5);
            app.signal            = [];
            app.nInterval         = 0;
            app.samplingTime      = [];
            app.samplingFrequency = 8;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.File = uimenu(app.UIFigure);
            app.File.Text = 'File';
            app.Print = uimenu(app.File);
            app.Print.MenuSelectedFcn = createCallbackFcn(app, @PrintMenuSelected, true);
            app.Print.Text = 'Print';
            app.Export = uimenu(app.File);
            app.Export.MenuSelectedFcn = createCallbackFcn(app, @ExportMenuSelected, true);
            app.Export.Text = 'Export';
            app.Exit = uimenu(app.File);
            app.Exit.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.Exit.Text = 'Exit';
            app.Channel = uimenu(app.UIFigure);
            app.Channel.Text = 'Channel';
            app.Add = uimenu(app.Channel);
            app.Add.MenuSelectedFcn = createCallbackFcn(app, @AddMenuSelected, true);
            app.Add.Text = 'Input Stack';
            app.Remove = uimenu(app.Channel);
            app.Remove.MenuSelectedFcn = createCallbackFcn(app, @RemoveMenuSelected, true);
            app.Remove.Text = 'Output Stack';
            app.ClearStackMenu = uimenu(app.Channel);
            app.ClearStackMenu.MenuSelectedFcn = createCallbackFcn(app, @ClearStackMenuSelected, true);
            app.ClearStackMenu.Text = 'Clear Stack';
            app.Analys = uimenu(app.UIFigure);
            app.Analys.MenuSelectedFcn = createCallbackFcn(app, @AnalysMenuSelected, true);
            app.Analys.Text = 'Analysis';
            app.SignalSettingPanel = uipanel(app.UIFigure);
            app.SignalSettingPanel.Title = 'Signal Setting';
            app.SignalSettingPanel.Visible = 'off';
            app.FrequencyEditFieldLabel = uilabel(app.SignalSettingPanel);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.SignalSettingPanel, 'text');
            app.HzLabel = uilabel(app.SignalSettingPanel);
            app.HzLabel.Text = 'Hz';
            app.WindowDropDownLabel = uilabel(app.SignalSettingPanel);
            app.WindowDropDownLabel.Text = 'Window';
            app.WindowDropDown = uidropdown(app.SignalSettingPanel);
            app.WindowDropDown.Items = {'rectwin', 'hann', 'hamming', 'flattopwin', 'blackman'};
            app.WindowDropDown.Value = 'rectwin';
            app.CancelButton = uibutton(app.SignalSettingPanel, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.ComfirmButton = uibutton(app.SignalSettingPanel, 'push');
            app.ComfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ComfirmButtonPushed, true);
            app.ComfirmButton.Text = 'Comfirm';
            app.TimeEditFieldLabel = uilabel(app.SignalSettingPanel);
            app.TimeEditFieldLabel.Text = 'Time';
            app.TimeEditField = uieditfield(app.SignalSettingPanel, 'numeric');
            app.secLabel = uilabel(app.SignalSettingPanel);
            app.secLabel.Text = 'sec';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AnalystBeta

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end